classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        TilingImageTab               matlab.ui.container.Tab
        SelectImageButton            matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        SelectButton                 matlab.ui.control.Button
        TargetImageEditFieldLabel    matlab.ui.control.Label
        TargetImageEditField         matlab.ui.control.EditField
        TilesXEditFieldLabel         matlab.ui.control.Label
        TilesXEditField              matlab.ui.control.NumericEditField
        OutputImageEditFieldLabel    matlab.ui.control.Label
        OutputImageEditField         matlab.ui.control.EditField
        TilesYEditFieldLabel         matlab.ui.control.Label
        TilesYEditField              matlab.ui.control.NumericEditField
        ClassifyTargetImageCheckBox  matlab.ui.control.CheckBox
        kNNClassifierTab             matlab.ui.container.Tab
        SelectImageButton_2          matlab.ui.control.Button
        TargetImageEditField_2Label  matlab.ui.control.Label
        TargetImageEditField_2       matlab.ui.control.EditField
        RunButton_2                  matlab.ui.control.Button
    end


    methods (Access = private)
 
    end

    methods (Access = public)
    
        
    end


    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            target_image = app.TargetImageEditField.Value;
            X = app.TilesXEditField.Value;
            Y = app.TilesYEditField.Value;
            source_file = 'all_images.txt';
            if(app.ClassifyTargetImageCheckBox.Value)
                load X_train
                load Y_train
                k=27;
                ic = ImageClassifier(X_train, Y_train, k).Train();
                x = ic.ParseImage(target_image);
                [label, score, cost] = ic.Predict(x);
                source_file = strcat(label, '_training.txt');
            end
            CompositeImage(target_image, source_file, X, Y);
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            load X_train
            load Y_train
            k=27;
            target_image = app.TargetImageEditField_2.Value;
            ic = ImageClassifier(X_train, Y_train, k).Train();
            X = ic.ParseImage(target_image);
            [label, score, cost] = ic.Predict(X);
            
            h = msgbox({strcat('Class: ', label), strcat('Score: ', num2str(max(score)*100), '%')}, 'Done!');
            
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
           [FileName,PathName] = uiputfile('output','Save as..');
           app.OutputImageEditField.Value = strcat(PathName, FileName);
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
           [FileName,PathName] = uigetfile('*.jpg','Select Target Image');
           app.TargetImageEditField.Value = strcat(PathName, FileName);
        end

        % Button pushed function: SelectImageButton_2
        function SelectImageButton_2Pushed(app, event)
           [FileName,PathName] = uigetfile('*.jpg','Select Target Image');
           app.TargetImageEditField_2.Value = strcat(PathName, FileName);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TilingImageTab = uitab(app.TabGroup);
            app.TilingImageTab.Title = 'Tiling Image';
            app.SelectImageButton = uibutton(app.TilingImageTab, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.RunButton = uibutton(app.TilingImageTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SelectButton = uibutton(app.TilingImageTab, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.TargetImageEditFieldLabel = uilabel(app.TilingImageTab);
            app.TargetImageEditFieldLabel.Text = 'Target Image';
            app.TargetImageEditField = uieditfield(app.TilingImageTab, 'text');
            app.TilesXEditFieldLabel = uilabel(app.TilingImageTab);
            app.TilesXEditFieldLabel.Text = 'Tiles X';
            app.TilesXEditField = uieditfield(app.TilingImageTab, 'numeric');
            app.TilesXEditField.Limits = [0 1024];
            app.TilesXEditField.ValueDisplayFormat = '%.0f';
            app.TilesXEditField.Value = 24;
            app.OutputImageEditFieldLabel = uilabel(app.TilingImageTab);
            app.OutputImageEditFieldLabel.Text = 'Output Image';
            app.OutputImageEditField = uieditfield(app.TilingImageTab, 'text');
            app.TilesYEditFieldLabel = uilabel(app.TilingImageTab);
            app.TilesYEditFieldLabel.Text = 'Tiles Y';
            app.TilesYEditField = uieditfield(app.TilingImageTab, 'numeric');
            app.TilesYEditField.Limits = [0 1024];
            app.TilesYEditField.ValueDisplayFormat = '%.0f';
            app.TilesYEditField.Value = 16;
            app.ClassifyTargetImageCheckBox = uicheckbox(app.TilingImageTab);
            app.ClassifyTargetImageCheckBox.Text = 'Classify Target Image';
            app.kNNClassifierTab = uitab(app.TabGroup);
            app.kNNClassifierTab.Title = 'kNN Classifier';
            app.SelectImageButton_2 = uibutton(app.kNNClassifierTab, 'push');
            app.SelectImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButton_2Pushed, true);
            app.SelectImageButton_2.Text = 'Select Image';
            app.TargetImageEditField_2Label = uilabel(app.kNNClassifierTab);
            app.TargetImageEditField_2Label.Text = 'Target Image';
            app.TargetImageEditField_2 = uieditfield(app.kNNClassifierTab, 'text');
            app.RunButton_2 = uibutton(app.kNNClassifierTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end